classdef AppForRocket < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ModellingARocketShipPanel     matlab.ui.container.Panel
        TimeofFlightsEditField        matlab.ui.control.NumericEditField
        TimeofFlightsEditFieldLabel   matlab.ui.control.Label
        DistancemEditField            matlab.ui.control.NumericEditField
        DistancemEditFieldLabel       matlab.ui.control.Label
        HeightAchievedEditField       matlab.ui.control.NumericEditField
        HeightAchievedEditFieldLabel  matlab.ui.control.Label
        ThetadegEditField             matlab.ui.control.NumericEditField
        ThetadegEditFieldLabel        matlab.ui.control.Label
        EnterButton                   matlab.ui.control.Button
        GeometryDropDown              matlab.ui.control.DropDown
        GeometryDropDownLabel         matlab.ui.control.Label
        TargetHeightmEditField        matlab.ui.control.NumericEditField
        TargetHeightmEditFieldLabel   matlab.ui.control.Label
        ThetaGuess2189degEditField    matlab.ui.control.NumericEditField
        ThetaGuess2189degEditFieldLabel  matlab.ui.control.Label
        ThetaGuess1189degEditField    matlab.ui.control.NumericEditField
        ThetaGuess1189degEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            a = app.TargetHeightmEditField.Value;
            b = app.ThetaGuess1189degEditField.Value;
            c = app.ThetaGuess2189degEditField.Value;

            switch app.GeometryDropDown.Value
                case 'Flat Earth'
                    [theta3,H3,TOF,~,Distance] = ShootingMethod(b,c,a,1);
                case 'Curved Earth'
                    [theta3,H3,TOF,~,Distance] = ShootingMethod(b,c,a,0);
            end

            app.HeightAchievedEditField.Value = H3;
            app.ThetadegEditField.Value = theta3;
            app.TimeofFlightsEditField.Value = TOF;
            app.DistancemEditField.Value = Distance;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModellingARocketShipPanel = uipanel(app.UIFigure);
            app.ModellingARocketShipPanel.Title = 'Modelling A Rocket Ship';
            app.ThetaGuess1189degEditFieldLabel = uilabel(app.ModellingARocketShipPanel);
            app.ThetaGuess1189degEditFieldLabel.Text = 'Theta Guess 1 ( 1- 89 deg)';
            app.ThetaGuess1189degEditField = uieditfield(app.ModellingARocketShipPanel, 'numeric');
            app.ThetaGuess1189degEditField.Limits = [1 89];
            app.ThetaGuess1189degEditField.Value = 1;
            app.ThetaGuess2189degEditFieldLabel = uilabel(app.ModellingARocketShipPanel);
            app.ThetaGuess2189degEditFieldLabel.Text = 'Theta Guess 2  ( 1- 89 deg)';
            app.ThetaGuess2189degEditField = uieditfield(app.ModellingARocketShipPanel, 'numeric');
            app.TargetHeightmEditFieldLabel = uilabel(app.ModellingARocketShipPanel);
            app.TargetHeightmEditFieldLabel.Text = 'Target Height (m)';
            app.TargetHeightmEditField = uieditfield(app.ModellingARocketShipPanel, 'numeric');
            app.GeometryDropDownLabel = uilabel(app.ModellingARocketShipPanel);
            app.GeometryDropDownLabel.Text = 'Geometry';
            app.GeometryDropDown = uidropdown(app.ModellingARocketShipPanel);
            app.GeometryDropDown.Items = {'Flat Earth', 'Curved Earth'};
            app.GeometryDropDown.Value = 'Flat Earth';
            app.EnterButton = uibutton(app.ModellingARocketShipPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ThetadegEditFieldLabel = uilabel(app.ModellingARocketShipPanel);
            app.ThetadegEditFieldLabel.Text = 'Theta (deg)';
            app.ThetadegEditField = uieditfield(app.ModellingARocketShipPanel, 'numeric');
            app.HeightAchievedEditFieldLabel = uilabel(app.ModellingARocketShipPanel);
            app.HeightAchievedEditFieldLabel.Text = 'Height Achieved';
            app.HeightAchievedEditField = uieditfield(app.ModellingARocketShipPanel, 'numeric');
            app.DistancemEditFieldLabel = uilabel(app.ModellingARocketShipPanel);
            app.DistancemEditFieldLabel.Text = 'Distance (m)';
            app.DistancemEditField = uieditfield(app.ModellingARocketShipPanel, 'numeric');
            app.TimeofFlightsEditFieldLabel = uilabel(app.ModellingARocketShipPanel);
            app.TimeofFlightsEditFieldLabel.Text = 'Time of Flight (s)';
            app.TimeofFlightsEditField = uieditfield(app.ModellingARocketShipPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppForRocket

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end